classdef r_estimator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        Lamp                   matlab.ui.control.Lamp
        LoadKButton            matlab.ui.control.Button
        LoadButton             matlab.ui.control.Button
        ExportButton           matlab.ui.control.Button
        ImageListBox           matlab.ui.control.ListBox
        CenterPanel            matlab.ui.container.Panel
        UIAxes                 matlab.ui.control.UIAxes
        RightPanel             matlab.ui.container.Panel
        SetButton              matlab.ui.control.StateButton
        CenterMarkerLabel      matlab.ui.control.Label
        DefaultButton          matlab.ui.control.Button
        CenterMarkerField      matlab.ui.control.EditField
        BoundaryMarkerLabel    matlab.ui.control.Label
        NewButton              matlab.ui.control.Button
        DeleteButton           matlab.ui.control.Button
        BoundaryMarkerListBox  matlab.ui.control.ListBox
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        Table
        K_All
        K_Mid
        MarkerState
        Division
    end
    
    methods (Access = private)
        
        function gaps = GetGap(app, mask, center, L)
            [h, w] = size(mask);
            gaps = [];
            for l = linspace(1, L, app.Division)
                phi = linspace(0, 2*pi, round(l)*10);
                x = int32(l*cos(phi) + center(1));
                y = int32(l*sin(phi) + center(2));
                xy = [x' y'];
                xy = unique(xy, 'rows'); % unique coordinates
                                
                % find index outside the image
                x = xy(:, 1);
                y = xy(:, 2);
                idx = x <= 0 | x > w | y <= 0 | y > h;
                
                % calculate gap
                xy = xy(~idx, :);
                ind = (xy(:, 1) - 1) * h + xy(:, 2);
                gap = sum(idx) + sum(~mask(ind));
                gaps = [gaps; gap];
            end
        end
        
        function RefreshPlot(app)
            idx = app.ImageListBox.Value;
            if ~isempty(idx)
                im = app.Table.Image{idx};
                imshow(im, 'Parent', app.UIAxes)
                
                hold(app.UIAxes, 'on')
                cm = app.Table.CenterMarker{idx};
                if ~isempty(cm)
                    plot(app.UIAxes, cm(1), cm(2), 'Marker', '+', 'LineWidth', 2, ...
                        'MarkerEdgeColor', 'b', 'MarkerSize', 10)
                end
                
                bm = app.Table.BoundaryMarkers{idx};
                if ~isempty(bm)
                    num = size(bm, 1);
                    for i = 1:num
                        plot(app.UIAxes, bm(i, 1), bm(i, 2), 'Marker', '+', 'LineWidth', 2, ...
                            'MarkerEdgeColor', 'r', 'MarkerSize', 10)
                    end
                end
                hold(app.UIAxes, 'off')
            else
                cla(app.UIAxes)
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Table = [];
            app.MarkerState = -1;
            app.UIAxes.Toolbar = [];
            app.Division = 500;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: LoadKButton
        function LoadKButtonPushed(app, event)
            [file, path] = uigetfile('*.csv;*.xls;*.xlsx');
            figure(app.UIFigure);
            if ~isequal(file, 0)
                fullpath = fullfile(path, file);
                tb = readtable(fullpath);
                try
                    app.K_All = mean(tb.k_all);
                    app.K_Mid = mean(tb.k_mid);
                catch
                    uialert(app.UIFigure, 'The table must contain required fields!', 'Failed');
                    return
                end
                
                app.Lamp.Enable = 'on';
                uialert(app.UIFigure, 'K values Loaded!', 'Success', 'Icon', 'success');
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
             [file, path] = uigetfile('*.csv;*.xls;*.xlsx');
            figure(app.UIFigure);
            if ~isequal(file, 0)
                fullpath = fullfile(path, file);
                tb = readtable(fullpath);
                try
                    tb = tb(:, {'Id', 'um_per_pixel', 'FilePath'});
                catch
                    uialert(app.UIFigure, 'The table must contain required fields!', 'Failed');
                    return
                end
                tb(any(ismissing(tb), 2), :) = [];
                
                [rows, ~] = size(tb);
                tb.Image = cell(rows, 1);
                for i = 1:rows
                    try
                        tb.Image{i} = imread(tb.FilePath{i});
                    catch
                        tb.Image{i} = [];
                    end
                end
                tb(cellfun(@isempty, tb.Image), :) = [];
                
                [rows, ~] = size(tb);
                if rows > 0
                    tb.CenterMarker = cell(rows, 1);
                    tb.BoundaryMarkers = cell(rows, 1);
                    app.Table = tb;
                    
                    app.ImageListBox.Items = tb.Id;
                    app.ImageListBox.ItemsData = 1:rows;
                    app.ImageListBox.Value = 1;
                else
                    app.Table = [];
                    app.ImageListBox.Items = {};
                    app.ImageListBox.Value = {};
                end
                app.CenterMarkerField.Value = '';
                app.BoundaryMarkerListBox.Items = {};
                app.BoundaryMarkerListBox.Value = {};
                app.SetButton.Value = 0;
                app.MarkerState = -1;
                RefreshPlot(app)
                
                uialert(app.UIFigure, 'Data Loaded!', 'Success', 'Icon', 'success');
            end
        end

        % Value changed function: ImageListBox
        function ImageListBoxValueChanged(app, event)
            idx = app.ImageListBox.Value;
            RefreshPlot(app)
            
            cm = app.Table.CenterMarker{idx};
            if ~isempty(cm)
                app.CenterMarkerField.Value = sprintf('%d, %d', cm(1), cm(2));
            else
                app.CenterMarkerField.Value = '';
            end
            app.SetButton.Value = 0;
            app.MarkerState = -1;
            
            bm = app.Table.BoundaryMarkers{idx};
            if ~isempty(bm)
                num = size(bm, 1);
                items = cell(num, 1);
                for i = 1:num
                    items{i} = sprintf('%d, %d', bm(i, 1), bm(i, 2));
                end
                app.BoundaryMarkerListBox.Items = items;
                app.BoundaryMarkerListBox.ItemsData = 1:num;
                app.BoundaryMarkerListBox.Value = {};
            else
                app.BoundaryMarkerListBox.Items = {};
                app.BoundaryMarkerListBox.Value = {};
            end
        end

        % Value changed function: SetButton
        function SetButtonValueChanged(app, event)
            if ~isempty(app.ImageListBox.Value)
                if app.SetButton.Value == 0
                    app.MarkerState = -1;
                else
                    app.MarkerState = 1;
                    app.BoundaryMarkerListBox.Value = {};
                end
            end
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            idx = app.ImageListBox.Value;
            if ~isempty(idx)
                center = round(size(app.Table.Image{idx})/2);
                app.Table.CenterMarker{idx} = [center(2), center(1)];
                app.CenterMarkerField.Value = sprintf('%d, %d', center(2), center(1));
                RefreshPlot(app)
            end
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            idx = app.ImageListBox.Value;
            if ~isempty(idx)
                app.Table.BoundaryMarkers{idx} = [app.Table.BoundaryMarkers{idx}; nan, nan];
                bm = app.Table.BoundaryMarkers{idx};
                num = size(bm, 1);
                items = cell(num, 1);
                for i = 1:num
                    items{i} = sprintf('%d, %d', bm(i, 1), bm(i, 2));
                end
                app.BoundaryMarkerListBox.Items = items;
                app.BoundaryMarkerListBox.ItemsData = 1:num;
                app.BoundaryMarkerListBox.Value = num;
                app.MarkerState = 2;
                app.SetButton.Value = 0;
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            idx = app.ImageListBox.Value;
            ind = app.BoundaryMarkerListBox.Value;
            if ~isempty(idx) && ~isempty(ind)
                app.Table.BoundaryMarkers{idx}(ind, :) = [];
                bm = app.Table.BoundaryMarkers{idx};
                num = size(bm, 1);
                if num > 0
                    items = cell(num, 1);
                    for i = 1:num
                        items{i} = sprintf('%d, %d', bm(i, 1), bm(i, 2));
                    end
                    app.BoundaryMarkerListBox.Items = items;
                    app.BoundaryMarkerListBox.ItemsData = 1:num;
                    app.BoundaryMarkerListBox.Value = {};
                else
                    app.BoundaryMarkerListBox.Items = {};
                    app.BoundaryMarkerListBox.Value = {};
                end
                app.MarkerState = -1;
                app.SetButton.Value = 0;
                RefreshPlot(app)
            end
        end

        % Value changed function: BoundaryMarkerListBox
        function BoundaryMarkerListBoxValueChanged(app, event)
            if ~isempty(app.ImageListBox.Value)
                app.MarkerState = 2;
                app.SetButton.Value = 0;
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            if app.MarkerState == 1
                idx = app.ImageListBox.Value;
                coord = get(app.UIAxes, 'CurrentPoint');
                row = round(coord(1, 2));
                col = round(coord(1, 1));
                if col > app.UIAxes.XLim(1) && col < app.UIAxes.XLim(2) && ...
                        row > app.UIAxes.YLim(1) && row < app.UIAxes.YLim(2)
                    app.Table.CenterMarker{idx} = [col, row];
                    app.CenterMarkerField.Value = sprintf('%d, %d', col, row);
                    RefreshPlot(app)
                end
            elseif app.MarkerState == 2
                idx = app.ImageListBox.Value;
                ind = app.BoundaryMarkerListBox.Value;
                coord = get(app.UIAxes, 'CurrentPoint');
                row = round(coord(1, 2));
                col = round(coord(1, 1));
                if col > app.UIAxes.XLim(1) && col < app.UIAxes.XLim(2) && ...
                        row > app.UIAxes.YLim(1) && row < app.UIAxes.YLim(2)
                    app.Table.BoundaryMarkers{idx}(ind, :) = [col, row];
                    app.BoundaryMarkerListBox.Items{ind} = sprintf('%d, %d', col, row);
                    RefreshPlot(app)
                end
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            if ~isempty(app.Table)
                empty = cellfun(@isempty, app.Table.CenterMarker) | ...
                        cellfun(@isempty, app.Table.BoundaryMarkers);
                if sum(empty) == 0
                    [file, path] = uiputfile('*.csv;*.xls;*.xlsx', ...
                        'File Selection', 'results.csv');
                    figure(app.UIFigure);
                    if isequal(file, 0)
                        return
                    end
                    fullpath = fullfile(path, file);
                    
                    num = size(app.Table, 1);
                    diameter_all = zeros(num, 1);
                    diameter_mid = zeros(num, 1);
                    theta_all = zeros(num, 1);
                    theta_mid = zeros(num, 1);
                    center_x = zeros(num, 1);
                    center_y = zeros(num, 1);
                    tb = app.Table(:, {'Id', 'um_per_pixel', 'FilePath'});
                    tb = addvars(tb, center_x, center_y, diameter_all, diameter_mid, ...
                        theta_all, theta_mid, 'Before', 'FilePath');
                    for i = 1:num
                        hsv = rgb2hsv(app.Table.Image{i});
                        se = strel('square', 3);
                        mask = hsv(:, :, 2) > 0.1;
                        mask = imopen(mask, se);
                        mask = imclose(mask, se);
                        cm = app.Table.CenterMarker{i};
                        tb.center_x(i) = cm(1);
                        tb.center_y(i) = cm(2);
                        bm = app.Table.BoundaryMarkers{i};
                        dists = [];
                        for j = 1:size(bm, 1)
                            dists = [dists norm(cm-bm(j, :))];
                        end
                        Lmin = min(dists);
                        Lmax = max(dists);
                        gaps = app.GetGap(mask, cm, Lmin);
                        Ks = ones(app.Division, 1) * app.K_All;
                        ls = linspace(1, Lmin, app.Division)';
                        Vars = [ls Ks];
                        mdl = fitnlm(Vars, gaps, @func_r, 1000);
                        tb.diameter_all(i) = 2 * mdl.Coefficients.Estimate(1) * ...
                            app.Table.um_per_pixel(i) / 1000;
                        tb.theta_all(i) = 180 * (1 - Lmax / mdl.Coefficients.Estimate(1) / pi);
                        ub = round(app.Division*0.8);
                        lb = round(app.Division*0.1);
                        gaps = gaps(lb:ub);
                        Ks = ones(app.Division, 1) * app.K_Mid;
                        Vars = [ls Ks];
                        Vars = Vars(lb:ub, :);
                        mdl = fitnlm(Vars, gaps, @func_r, 1000);
                        tb.diameter_mid(i) = 2 * mdl.Coefficients.Estimate(1) * ...
                            app.Table.um_per_pixel(i) / 1000;
                        tb.theta_mid(i) = 180 * (1 - Lmax / mdl.Coefficients.Estimate(1) / pi);
                    end
                    writetable(tb, fullpath);
                    uialert(app.UIFigure, 'Data Exported!', 'Success', 'Icon', 'success');
                else
                    uialert(app.UIFigure, 'Some images are not marked!', 'Failed');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Lamp = uilamp(app.LeftPanel);
            app.Lamp.Enable = 'off';
            app.LoadKButton = uibutton(app.LeftPanel, 'push');
            app.LoadKButton.ButtonPushedFcn = createCallbackFcn(app, @LoadKButtonPushed, true);
            app.LoadKButton.Text = 'Load K';
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ExportButton = uibutton(app.LeftPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ImageListBox = uilistbox(app.LeftPanel);
            app.ImageListBox.Items = {};
            app.ImageListBox.ValueChangedFcn = createCallbackFcn(app, @ImageListBoxValueChanged, true);
            app.ImageListBox.Value = {};
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.Visible = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.SetButton = uibutton(app.RightPanel, 'state');
            app.SetButton.ValueChangedFcn = createCallbackFcn(app, @SetButtonValueChanged, true);
            app.SetButton.Text = 'Set';
            app.CenterMarkerLabel = uilabel(app.RightPanel);
            app.CenterMarkerLabel.Text = 'Center Marker';
            app.DefaultButton = uibutton(app.RightPanel, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.CenterMarkerField = uieditfield(app.RightPanel, 'text');
            app.CenterMarkerField.Editable = 'off';
            app.BoundaryMarkerLabel = uilabel(app.RightPanel);
            app.BoundaryMarkerLabel.Text = 'Boundary Marker';
            app.NewButton = uibutton(app.RightPanel, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New';
            app.DeleteButton = uibutton(app.RightPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.BoundaryMarkerListBox = uilistbox(app.RightPanel);
            app.BoundaryMarkerListBox.Items = {};
            app.BoundaryMarkerListBox.ValueChangedFcn = createCallbackFcn(app, @BoundaryMarkerListBoxValueChanged, true);
            app.BoundaryMarkerListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = r_estimator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end